classdef controlTemperatura < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ControlPanel                    matlab.ui.container.Panel
        StatusLabel_A                   matlab.ui.control.Label
        ActualizarButton                matlab.ui.control.Button
        IntervalodemuestreomsEditField  matlab.ui.control.NumericEditField
        IntervalodemuestreosLabel       matlab.ui.control.Label
        HistresisCEditField             matlab.ui.control.NumericEditField
        HistresisCEditFieldLabel        matlab.ui.control.Label
        EscalonButton                   matlab.ui.control.Button
        SetpointCEditField              matlab.ui.control.NumericEditField
        SetpointCLabel                  matlab.ui.control.Label
        ConfiguracinPanel               matlab.ui.container.Panel
        DesconectarButton               matlab.ui.control.Button
        PORTDropDown                    matlab.ui.control.DropDown
        PORTDropDownLabel               matlab.ui.control.Label
        BaudRateDropDown                matlab.ui.control.DropDown
        BaudRateDropDownLabel           matlab.ui.control.Label
        ConectarButton                  matlab.ui.control.Button
        TemperaturaGauge                matlab.ui.control.LinearGauge
        TemperaturaGaugeLabel           matlab.ui.control.Label
        EstadodeconexionLamp            matlab.ui.control.Lamp
        EstadodeconexionLampLabel       matlab.ui.control.Label
        EstadodelcalefactorLamp         matlab.ui.control.Lamp
        EstadodelcalefactorLampLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        conexionSerial % Conexion de serie
        tempData        % guarda temperatura
        timeData        % guarda tiempo 
        setPoint
        histeresis
        intervalo
        historial_SP
        plotTemp
        plotSetpoint
        startTime
        estadoConexion = struct('conectado', 0xff, 'desconectado', 0xfe);

    end
    
    methods (Access = public)

        function lectura_DataSerial(app, src, ~)
            % Leer la línea de datos crudos del puerto serie
            inputLine = readline(src);
            disp(inputLine);       % Mostrar los datos crudos recibidos


            if (length(inputLine{:}) == 5)
                %% Separo la informacion en temperatura y estado calefactor
                temperatura = str2double(inputLine{:}(1:4))*0.1;
                estadoCalefactor = str2double(inputLine{:}(5));


                %% Actualizar el estado del calefactor
                if estadoCalefactor == 1
                    app.EstadodelcalefactorLamp.Color = [0.39,0.83,0.07]; % Verde
                else
                    app.EstadodelcalefactorLamp.Color = [0.78, 0.1, 0.1]; %rojo
                end

                %% Actualizar el valor de temperatura
                app.TemperaturaGauge.Value = temperatura;

                %% Veo si existen las variables, si no existen las crea
                if isempty(app.tempData) || isempty(app.timeData)
                    app.tempData     = [];
                    app.timeData     = [];
                    app.historial_SP = [];
                end


                tNow = datetime('now');  % Tiempo actual
                elapsedSec = seconds(tNow - app.startTime);

                % Guardar nuevos datos
                app.tempData = [app.tempData, temperatura];
                app.timeData = [app.timeData, elapsedSec];
                app.historial_SP = [app.historial_SP, app.setPoint];
                
                % Agregar los nuevos puntos a los animatedline
                addpoints(app.plotTemp, elapsedSec, temperatura);
                addpoints(app.plotSetpoint, elapsedSec, app.setPoint);
                
                % Ajustar límites del eje. Convertir datetime a datenum para animatedline:
                app.UIAxes.XLim = [max(0, elapsedSec-30), elapsedSec];
                app.UIAxes.YLim = [0, 85];

                % Actualizar la leyenda (si es necesario, normalmente se configura una sola vez)
                legend(app.UIAxes, {'Temperatura (DS1820)', 'Set-Point'});
                drawnow;

            else
                disp('Error: Datos malformados o incompletos.');
            end
        end

        function setAppState(app,state)
            switch state
                case "Seleccion dispositivo"
                    app.BaudRateDropDown.Enable  = "on";
                    app.PORTDropDown.Enable      = "on";
                    app.DesconectarButton.Enable = "off";
                    app.ConectarButton.Enable    = "on";
                    app.DesconectarButton.Enable = "off";
                    app.StatusLabel_A.Visible    = "off";
                    app.ActualizarButton.Enable  = "off";
                case "Conectado"
                    app.EstadodeconexionLamp.Color = [0.39,0.83,0.07]; % Verde;
                    app.BaudRateDropDown.Enable  = "off";
                    app.PORTDropDown.Enable      = "off";
                    app.ActualizarButton.Enable  = "on";
                    app.DesconectarButton.Enable = "on";
                    app.ConectarButton.Enable    = "off";
                case "Desconectado"
                    app.EstadodeconexionLamp.Color = [0.78, 0.1, 0.1]; %rojo
                    app.ActualizarButton.Enable = "off";
                    app.ConectarButton.Enable = "on";
                    app.DesconectarButton.Enable = "off";
                    app.PORTDropDown.Enable = "on";
                    app.BaudRateDropDown.Enable = "on";
                case "Error conectado"
                    app.ConectarButton.Enable = "off";
                    pause(2);
                    app.EstadodeconexionLamp.Color = [0.78, 0.1, 0.1]; %rojo
                    disp("Error conectando a la placa");
                    app.BaudRateDropDown.Enable  = "on";
                    app.PORTDropDown.Enable      = "on";
                    app.ActualizarButton.Enable  = "off";
                    app.DesconectarButton.Enable = "off";
                    app.ConectarButton.Enable    = "on";
                case "Actualizando"
                    % Actualizar el estado en la GUI
                    app.StatusLabel_A.Visible = "on";
                    app.EscalonButton.Enable = "off";
                    app.ActualizarButton.Enable = "off";
                    app.StatusLabel_A.Text = "Parámetros enviados exitosamente";
                    pause(1);
                    app.StatusLabel_A.Visible = "off";
                    app.EscalonButton.Enable = "on";
                    app.ActualizarButton.Enable = "on";
                case "Error actualizado"
                    % Manejo de errores y actualizar el estado en la GUI
                    app.StatusLabel_A.Visible = "on";
                    app.ActualizarButton.Enable = "off";
                    app.StatusLabel_A.Text = "Error enviando parámetros";
                    pause(1);
                    app.StatusLabel_A.Visible = "off";
                    app.ActualizarButton.Enable = "on";
                case "Cerrar sesion"
                    app.EstadodeconexionLamp.Color    = [0.78, 0.1, 0.1]; %rojo
                    app.EstadodelcalefactorLamp.Color = [0.78, 0.1, 0.1]; %rojo
                    app.ActualizarButton.Enable       = "off";
                    delete(app)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Funcion que se ejecuta cuando se inicializa la aplicacion
            app.PORTDropDown.Items = serialportlist("available");
            app.BaudRateDropDown.Items = {'1200', '2400','4800','9600','19200', '38400'};
            app.UIFigure.Resize = 'off';

            setAppState(app,"Seleccion dispositivo");
            %Lee valores al inciar programa
            app.setPoint   = app.SetpointCEditField.Value;
            app.histeresis = app.HistresisCEditField.Value;
            app.intervalo  = app.IntervalodemuestreomsEditField.Value;

            % Creo los objetos animatedLine
            app.plotTemp = animatedline(app.UIAxes, 'Color', 'r', 'LineWidth', 1.5);
            % Set point de color GRIS
            app.plotSetpoint = animatedline(app.UIAxes, 'Color',[0.5, 0.5, 0.5], 'LineStyle', '--', 'LineWidth', 1.5);
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            portChoice     = app.PORTDropDown.Value;
            baudRateChoice = app.BaudRateDropDown.Value;

            try
                app.conexionSerial = serialport(portChoice,str2double(baudRateChoice));
                configureTerminator(app.conexionSerial,"CR");
                app.conexionSerial.Timeout = 10; % Establecer el tiempo de espera para operaciones
                % Lectura de datos seriales
                configureCallback(app.conexionSerial,"terminator",@(src, event) lectura_DataSerial(app,src,event));
               
                % Manda mensaje al micro de que se conecto a funcion: check_comm()
                %message = num_to_ascii(app,1); % Mando un 1 para establecer conexion 
                
                %% Se cargan los valores iniciales de la App
                app.setPoint   = app.SetpointCEditField.Value;
                app.histeresis = app.HistresisCEditField.Value;
                app.intervalo  = app.IntervalodemuestreomsEditField.Value;

                write(app.conexionSerial,round(app.setPoint),"uint8");
                write(app.conexionSerial,round(app.histeresis),"uint8");
                write(app.conexionSerial,round(app.intervalo),"uint16");  
                write(app.conexionSerial,app.estadoConexion.conectado,"char"); %Finaliza el mensaje
                setAppState(app,"Conectado");
                app.startTime = datetime('now');  % Guarda el instante de inicio

            catch ME
                setAppState(app,"Error conectado");
            end
        
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            app.setPoint   = app.SetpointCEditField.Value;
            app.histeresis = app.HistresisCEditField.Value;
            app.intervalo  = app.IntervalodemuestreomsEditField.Value;

            %% Verifico que este conectado
            if isempty(app.conexionSerial) || ~isvalid(app.conexionSerial)
                return;
            end

            %% Construir el mensaje a enviar
            try
                write(app.conexionSerial,round(app.setPoint),"uint8");
                write(app.conexionSerial,round(app.histeresis),"uint8");
                write(app.conexionSerial,round(app.intervalo),"uint16");  
                write(app.conexionSerial,app.estadoConexion.conectado,"char"); %Finaliza el mensaje
                setAppState(app,"Actualizando")  % Actualizar el estado en la GUI
            catch ME
                disp(ME.message);
                setAppState(app,"Error actualizado");

            end
        end

        % Button pushed function: EscalonButton
        function escalonButton(app, event)
            app.setPoint = app.SetpointCEditField.Value + 10;  %incremento del setpoint
            
            if app.setPoint >= 80
                app.SetpointCEditField.Value = 80;
            else
                app.SetpointCEditField.Value = app.setPoint; % actualiza el setpoint en la GUI
            end
            
            % Opcional
            ActualizarButtonPushed(app, event); %envia data al micro

        end

        % Close request function: UIFigure
        function UI_close(app, event)
            if ~isempty(app.conexionSerial)
                % Cerrar el puerto serie
                clear app.conexionSerial;
            end
            setAppState(app,"Cerrar sesion");
            
        end

        % Callback function
        function activarDarkMode(app, event)
            % No puedo borrar la funcion
        end

        % Button pushed function: DesconectarButton
        function desconectarButtonPushed(app, event)
            write(app.conexionSerial,app.estadoConexion.desconectado,"char"); %Finaliza el mensaje
            flush(app.conexionSerial);
            delete(app.conexionSerial);
            setAppState(app,"Desconectado");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UI_close, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'tiempo vs. temperatura')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Temperatura [C°]')
            zlabel(app.UIAxes, 'Z')
            app.EstadodelcalefactorLampLabel = uilabel(app.UIFigure);
            app.EstadodelcalefactorLampLabel.Text = 'Estado del calefactor';
            app.EstadodelcalefactorLamp = uilamp(app.UIFigure);
            app.EstadodeconexionLampLabel = uilabel(app.UIFigure);
            app.EstadodeconexionLampLabel.Text = 'Estado de conexion';
            app.EstadodeconexionLamp = uilamp(app.UIFigure);
            app.TemperaturaGaugeLabel = uilabel(app.UIFigure);
            app.TemperaturaGaugeLabel.Text = 'Temperatura';
            app.TemperaturaGauge = uigauge(app.UIFigure, 'linear');
            app.ConfiguracinPanel = uipanel(app.UIFigure);
            app.ConfiguracinPanel.Title = 'Configuración';
            app.ConectarButton = uibutton(app.ConfiguracinPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.BaudRateDropDownLabel = uilabel(app.ConfiguracinPanel);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.ConfiguracinPanel);
            app.BaudRateDropDown.Items = {''};
            app.BaudRateDropDown.Value = '';
            app.PORTDropDownLabel = uilabel(app.ConfiguracinPanel);
            app.PORTDropDownLabel.Text = 'PORT';
            app.PORTDropDown = uidropdown(app.ConfiguracinPanel);
            app.PORTDropDown.Items = {''};
            app.PORTDropDown.Value = '';
            app.DesconectarButton = uibutton(app.ConfiguracinPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @desconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.SetpointCLabel = uilabel(app.ControlPanel);
            app.SetpointCLabel.Text = 'Set-point [°C]';
            app.SetpointCEditField = uieditfield(app.ControlPanel, 'numeric');
            app.SetpointCEditField.Limits = [0 80];
            app.SetpointCEditField.Value = 20;
            app.EscalonButton = uibutton(app.ControlPanel, 'push');
            app.EscalonButton.ButtonPushedFcn = createCallbackFcn(app, @escalonButton, true);
            app.EscalonButton.Text = 'Escalon';
            app.HistresisCEditFieldLabel = uilabel(app.ControlPanel);
            app.HistresisCEditFieldLabel.Text = 'Histéresis [°C]';
            app.HistresisCEditField = uieditfield(app.ControlPanel, 'numeric');
            app.HistresisCEditField.Limits = [0 10];
            app.HistresisCEditField.Value = 3;
            app.IntervalodemuestreosLabel = uilabel(app.ControlPanel);
            app.IntervalodemuestreosLabel.Text = {'Intervalo de '; 'muestreo [ms]'};
            app.IntervalodemuestreomsEditField = uieditfield(app.ControlPanel, 'numeric');
            app.IntervalodemuestreomsEditField.Limits = [0 Inf];
            app.IntervalodemuestreomsEditField.Value = 3000;
            app.ActualizarButton = uibutton(app.ControlPanel, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.StatusLabel_A = uilabel(app.ControlPanel);
            app.StatusLabel_A.Text = 'StatusLabel_A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlTemperatura

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end